classdef controlAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        GridLayout6      matlab.ui.container.GridLayout
        GridLayout8      matlab.ui.container.GridLayout
        HardwarePanel    matlab.ui.container.Panel
        GridLayout11     matlab.ui.container.GridLayout
        PortList         matlab.ui.control.DropDown
        PortLabel        matlab.ui.control.Label
        Refresh          matlab.ui.control.Button
        GridLayout10     matlab.ui.container.GridLayout
        BDiscon          matlab.ui.control.Button
        BCon             matlab.ui.control.Button
        GridLayout7      matlab.ui.container.GridLayout
        ConnectionLabel  matlab.ui.control.Label
        Lamp             matlab.ui.control.Lamp
        GridLayout3      matlab.ui.container.GridLayout
        PanelR           matlab.ui.container.Panel
        IprogOn          matlab.ui.control.Switch
    end

    
    properties (Access = private)
        TIME_STEP = 0.01 % fixed time step of calling SendData function
        % define colors
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        s % serial port
        t % timer (for update GUI and send data)
        dataToSend = zeros(1, 2) % allocate for data to send
        lifeSign = 0;
    end
    
    methods (Access = private)
               
        function sendData(app, dataToSend)
            % if output buffer is empty write values to serial port in asyncronous mode
            if app.s.BytesToOutput == 0
                %color_values = 0.5 * color_values; % decrease maximum brightness to prevent your eyes burning
                values_to_send = uint8(dataToSend); % scale color values to 255..0 uint8 range
                fwrite(app.s, values_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
            end
        end
        
        function readData(~, ~, ~)
            % read and scale data from serial port
            %app.light_value = double(fread(app.s, 1, 'uint16')) / 10;
        end
        
        function timerFunction(app, ~, ~)
            % update data to send
            app.lifeSign = mod(app.lifeSign + 1,255);
            app.dataToSend = [app.IprogOn.Value, app.lifeSign];

            % send data
            app.sendData(app.dataToSend);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set up swithes values
            app.IprogOn.ItemsData = [0 1];
            app.IprogOn.Value = 0;
                        
            % read knob values into array
            app.dataToSend = [app.IprogOn.Value, app.lifeSign];
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.timerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.TIME_STEP;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.t.running == "on"
                stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = app.RED_COLOR;
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if app.t.running == "off"

                app.s = serial(app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BytesAvailableFcnCount = 2;
                app.s.BytesAvailableFcnMode = 'byte';
                app.s.BytesAvailableFcn = @app.readData;
                fopen(app.s);

                start(app.t);
                app.Lamp.Color = app.GREEN_COLOR;
            end
        end

        % Value changed function: IprogOn
        function SwValueChanged(app, event)
            % change lamp color if switch value has changed
            sw = event.Source; % define switch
            app.IprogOn.Value = sw.Value;
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.BDisconButtonPushed();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Instrument Control over Serial';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PanelR = uipanel(app.GridLayout3);
            app.PanelR.Title = 'Enable current programming input';
            app.IprogOn = uiswitch(app.PanelR, 'slider');
            app.IprogOn.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.IprogOn.Value = 'On';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Lamp = uilamp(app.GridLayout7);
            app.ConnectionLabel = uilabel(app.GridLayout7);
            app.ConnectionLabel.Text = 'Connection';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.BCon = uibutton(app.GridLayout10, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.GridLayout10, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.HardwarePanel = uipanel(app.GridLayout8);
            app.HardwarePanel.Title = 'Hardware';
            app.GridLayout11 = uigridlayout(app.HardwarePanel);
            app.Refresh = uibutton(app.GridLayout11, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel = uilabel(app.GridLayout11);
            app.PortLabel.Text = 'Port';
            app.PortList = uidropdown(app.GridLayout11);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end